classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        ExportButton              matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        DatauploadPanel           matlab.ui.container.Panel
        LabelviewtypeButtonGroup  matlab.ui.container.ButtonGroup
        ContinuousviewButton      matlab.ui.control.RadioButton
        BinaryviewButton          matlab.ui.control.RadioButton
        UploadlabelsButton        matlab.ui.control.Button
        UploadXButton             matlab.ui.control.Button
        ModeltrainingPanel        matlab.ui.container.Panel
        KEditField                matlab.ui.control.NumericEditField
        KEditFieldLabel           matlab.ui.control.Label
        TrainmodelButton          matlab.ui.control.Button
        CVsplitsEditField         matlab.ui.control.NumericEditField
        CVsplitsEditFieldLabel    matlab.ui.control.Label
        runsEditField             matlab.ui.control.NumericEditField
        runsEditFieldLabel        matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        X % EEG + Q
        y % labels
        modelType % binary vs cont.
        stabilityRuns % = 10
        numOfFolds % = 5
        K % init number of factors
        resTable % Description
        dataset % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.K = app.KEditField.Value;
            app.stabilityRuns = app.runsEditField.Value;
            app.numOfFolds = app.CVsplitsEditField.Value;
            app.resTable = table();
            app.modelType = LogicUtils.ternary(app.BinaryviewButton.Value, 'bGFA', 'cGFA');
        end

        % Button pushed function: UploadXButton
        function UploadXButtonPushed(app, event)
            % Open file selection dialog (for CSV files only)
            [file, path] = uigetfile('*.csv', 'Select a CSV file');
            
            % Check if a file was selected
            if isequal(file, 0)
                app.Label.Text = 'No file selected';
            else
                % Full path to the file
                fullFileName = fullfile(path, file);

                splitName = split(file, '.');

                % Extract the first part
                fileName = splitName{1};

                app.dataset = fileName;

                app.X = readmatrix(fullFileName, 'FileType', 'text');
                app.Label.Text = [fileName, ' uploaded'];
            end
            drawnow;
        end

        % Button pushed function: UploadlabelsButton
        function UploadlabelsButtonPushed(app, event)
            % Open file selection dialog (for CSV files only)
            [file, path] = uigetfile('*.csv', 'Select a CSV file');
            
            % Check if a file was selected
            if isequal(file, 0)
                app.Label.Text = 'No file selected';
            else
                % Full path to the file
                fullFileName = fullfile(path, file);
                
                % % Load the CSV file into MATLAB
                % data = readtable(fullFileName);

                app.y = readmatrix(fullFileName, 'FileType', 'text');
                app.Label.Text = 'Labels uploaded';
            end
            drawnow;
        end

        % Selection changed function: LabelviewtypeButtonGroup
        function LabelviewtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.LabelviewtypeButtonGroup.SelectedObject;

            % Perform an action based on the selected button
            if strcmp(selectedButton.Text, 'Binary view')
                app.modelType = 'bGFA';
            elseif strcmp(selectedButton.Text, 'Continuous view')
                app.modelType = 'cGFA';
            end
                    
        end

        % Button pushed function: TrainmodelButton
        function TrainmodelButtonPushed(app, event)

            res = Results(app.numOfFolds); % obj
            cv = cvpartition(app.y, 'KFold', app.numOfFolds);

            for foldIdx = 1:app.numOfFolds
                % [X_tr, y_tr, X_te, y_te] = Datasets.trainTestSplit(X, y, true);
        
                % Get training and testing indices for fold 'foldIdx'
                trainIdx = cv.training(foldIdx);
                testIdx = cv.test(foldIdx);
        
                % Train
                X_tr = app.X(trainIdx, :); y_tr = app.y(trainIdx); 
                % Test
                X_te = app.X(testIdx, :); y_te = app.y(testIdx); 
        
        
                bestModel = NaN;
                bestElbo = -inf;
                bestIter = 0;
        
                for s = 1:app.stabilityRuns
                    if app.modelType == "cGFA"
                        model = SGFA({X_tr', y_tr'}, app.K, 10000, 1e-4); % Views are expected in DxN
                    else
                        model = BGFA({X_tr', y_tr'}, 1, app.K, 'B', 10000, 1e-4); % Views are expected in DxN
                    end

                    [elboVals, iter] = model.fit(10);
        
                    if elboVals(end) > bestElbo
                        bestModel = model;
                        bestElbo = elboVals(end);
                        bestIter = iter;
                    end
                end
        
                [K_eff, predictions_te] = bestModel.makePredictions(X_tr, y_tr, X_te);
        
                res.computeAndAppendMetrics(foldIdx, y_te, predictions_te, K_eff, bestIter, bestElbo);
            end

            app.resTable = [app.resTable; res.computeMeanAndStd(app.modelType, app.dataset)];
        end

        % Value changed function: runsEditField
        function runsEditFieldValueChanged(app, event)
            app.stabilityRuns = app.runsEditField.Value;
            
        end

        % Value changed function: CVsplitsEditField
        function CVsplitsEditFieldValueChanged(app, event)
            app.numOfFolds = app.CVsplitsEditField.Value;
            
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            app.K = app.KEditField.Value;
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            writetable(app.resTable, 'GUI_results.csv');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {345, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModeltrainingPanel = uipanel(app.LeftPanel);
            app.ModeltrainingPanel.Title = 'Model training';
            app.runsEditFieldLabel = uilabel(app.ModeltrainingPanel);
            app.runsEditFieldLabel.Text = '# runs';
            app.runsEditField = uieditfield(app.ModeltrainingPanel, 'numeric');
            app.runsEditField.Limits = [1 Inf];
            app.runsEditField.ValueDisplayFormat = '%.0f';
            app.runsEditField.ValueChangedFcn = createCallbackFcn(app, @runsEditFieldValueChanged, true);
            app.runsEditField.Value = 10;
            app.CVsplitsEditFieldLabel = uilabel(app.ModeltrainingPanel);
            app.CVsplitsEditFieldLabel.Text = 'CV splits';
            app.CVsplitsEditField = uieditfield(app.ModeltrainingPanel, 'numeric');
            app.CVsplitsEditField.Limits = [1 Inf];
            app.CVsplitsEditField.ValueDisplayFormat = '%.0f';
            app.CVsplitsEditField.ValueChangedFcn = createCallbackFcn(app, @CVsplitsEditFieldValueChanged, true);
            app.CVsplitsEditField.Value = 5;
            app.TrainmodelButton = uibutton(app.ModeltrainingPanel, 'push');
            app.TrainmodelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainmodelButtonPushed, true);
            app.TrainmodelButton.Text = 'Train model';
            app.KEditFieldLabel = uilabel(app.ModeltrainingPanel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.ModeltrainingPanel, 'numeric');
            app.KEditField.Limits = [1 Inf];
            app.KEditField.ValueDisplayFormat = '%.0f';
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.KEditField.Value = 100;
            app.DatauploadPanel = uipanel(app.LeftPanel);
            app.DatauploadPanel.Title = 'Data upload';
            app.UploadXButton = uibutton(app.DatauploadPanel, 'push');
            app.UploadXButton.ButtonPushedFcn = createCallbackFcn(app, @UploadXButtonPushed, true);
            app.UploadXButton.Text = 'Upload X';
            app.UploadlabelsButton = uibutton(app.DatauploadPanel, 'push');
            app.UploadlabelsButton.ButtonPushedFcn = createCallbackFcn(app, @UploadlabelsButtonPushed, true);
            app.UploadlabelsButton.Text = 'Upload labels';
            app.LabelviewtypeButtonGroup = uibuttongroup(app.DatauploadPanel);
            app.LabelviewtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LabelviewtypeButtonGroupSelectionChanged, true);
            app.LabelviewtypeButtonGroup.Title = 'Label view type';
            app.BinaryviewButton = uiradiobutton(app.LabelviewtypeButtonGroup);
            app.BinaryviewButton.Text = 'Binary view';
            app.BinaryviewButton.Value = true;
            app.ContinuousviewButton = uiradiobutton(app.LabelviewtypeButtonGroup);
            app.ContinuousviewButton.Text = 'Continuous view';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Tag = 'Label';
            app.Label.Text = '';
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end